classdef es_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        Panel         matlab.ui.container.Panel
        ySlider       matlab.ui.control.Slider
        ySliderLabel  matlab.ui.control.Label
        xSlider       matlab.ui.control.Slider
        xSliderLabel  matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
        TabGroup      matlab.ui.container.TabGroup
        PlotTab       matlab.ui.container.Tab
        UIAxes        matlab.ui.control.UIAxes
        VolumeTab     matlab.ui.container.Tab
        UIAxes2       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        L=10 % Description
        r=2 % Description
    end
    
    methods (Access = private)
        
        function fvec = fk(app, q1, q2, r, L) % forward kinematics
            l1 = q1*r;
            l2 = q2*r;
            x = (L^2 + l1^2 - l2^2)/(2*L);
            fvec = [x;
                    -sqrt((l1^2 - x^2))
                   ];
        end

        function ivec = ik(app, x, y, r, L) % inverse kinematics
            ivec = [sqrt((x^2 + y^2))/r;
                    sqrt(((L - x)^2 + y^2))/r
                   ];
        end

        function mapVolume(app, dim, r, L) % function to plot the volume
            x = 0:L/(dim-1):L;
            y = -L:L/(dim-1):0;

            [v, s] = meshgrid(x, y);
    
            tuples = [v(:) s(:)];

            volumes = -1*ones(length(tuples), 1);

            for i=1:length(tuples)
                vec = tuples(i, :);
                try
                    q = app.ik(vec(1), vec(2), r, L);
                    J = app.jac(q(1), q(2), r, L);
                    volumes(i) = sqrt(det(J*J'));
                catch
                    volumes(i) = -1;
                end
            end
            volumes = reshape(volumes, [], cast(sqrt(length(tuples)), "uint32"));

            ax = app.UIAxes2;
            cla(ax);
            surf(ax, v, s, volumes);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'volume');
        end

        function mjac = jac(app, q1, q2, r, L) % jacobian calculation
            q = sym('q_%d',[1 2]);
    
            l1 = q(1)*r;
            l2 = q(2)*r;
            x = (L^2 + l1^2 - l2^2)/(2*L);
            fvec = [x;
                    -sqrt((l1^2 - x^2))
                   ];
            
            mjac = double(subs(jacobian(fvec, q), q, [q1, q2]));
        end

        function plotEllipse(app, q1, q2, r, L, posx, posy)
            C = [posx, posy]; % center of the ellipse in reality in the gui is the point on which we calculate the inverse kinematics

            t = linspace(0, 2*pi, 100); % code for plotting the ellipse

            J = app.jac(q1, q2, r, L);

            [vec, val] = eig(J);

            eigs = sort([val(1, 1) val(2, 2)], 'descend');

            theta1 = atan2(vec(2, 2), vec(2, 1));
            theta2 = atan2(vec(1, 2), vec(1, 1));

            x = C(1) + eigs(1)*cos(t);
            y = C(2) + eigs(2)*sin(t);
            
            ax = app.UIAxes;
            hold(ax,'on');
            cla(ax);
            ax.XLim = [0 L];
            ax.YLim = [-L 0];
            plot(ax, [0, posx], [0, posy], "-.", 'Color', [0.5 0 0.8]);
            plot(ax, [L, posx], [0, posy], "-.", 'Color', [0.5 0 0.8]);
            plot(ax, [C(1), C(1) + val(1, 1)*cos(theta1)], [C(2), C(2) + val(2, 2)*sin(theta1)])
            plot(ax, [C(1), C(1) + val(1, 1)*cos(theta2)], [C(2), C(2) + val(2, 2)*sin(theta2)])
            plot(ax, x, y);
            hold(ax,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xSlider.Value = 5;
            app.ySlider.Value = -5;
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
            app.mapVolume(10, app.r, app.L);
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            q = app.ik(app.xSlider.Value, app.ySlider.Value, app.r, app.L);
            app.plotEllipse(q(1), q(2), app.r, app.L, app.xSlider.Value, app.ySlider.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {355, 355};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.xSliderLabel = uilabel(app.Panel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.Panel);
            app.xSlider.Limits = [1e-06 10];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 5;
            app.ySliderLabel = uilabel(app.Panel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.Panel);
            app.ySlider.Limits = [-10 1e-06];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = -5;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, '2D rope robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VolumeTab = uitab(app.TabGroup);
            app.VolumeTab.Title = 'Volume';
            app.UIAxes2 = uiaxes(app.VolumeTab);
            title(app.UIAxes2, 'volume plot')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end